classdef scoreboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CodyAmbassadorContestLabel      matlab.ui.control.Label
        ScoreboardBuilderLabel          matlab.ui.control.Label
        GenerateScoreboardButton        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        OutputfileoptionsPanel          matlab.ui.container.Panel
        PNGSwitchLabel                  matlab.ui.control.Label
        PNGSwitch                       matlab.ui.control.Switch
        GIFSwitchLabel                  matlab.ui.control.Label
        GIFSwitch                       matlab.ui.control.Switch
        HTMLSwitchLabel                 matlab.ui.control.Label
        HTMLSwitch                      matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        ScoreboardSettingsTab           matlab.ui.container.Tab
        SchoolNameEditFieldLabel        matlab.ui.control.Label
        SchoolNameEditField             matlab.ui.control.EditField
        EmailDomainEditFieldLabel       matlab.ui.control.Label
        EmailDomainEditField            matlab.ui.control.EditField
        ProblemGroupEditFieldLabel      matlab.ui.control.Label
        ProblemGroupEditField           matlab.ui.control.EditField
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        ScoreboardFilenameEditFieldLabel  matlab.ui.control.Label
        ScoreboardFilenameEditField     matlab.ui.control.EditField
        PNGOptionsTab                   matlab.ui.container.Tab
        MaxPlayersDisplayedEditFieldLabel  matlab.ui.control.Label
        MaxPlayersDisplayedEditField    matlab.ui.control.NumericEditField
        BackgroundImageEditFieldLabel   matlab.ui.control.Label
        BackgroundImageEditField        matlab.ui.control.EditField
        ChooseimageButton               matlab.ui.control.Button
        ThemeColor2DropDownLabel_2      matlab.ui.control.Label
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        ThemeColor2DropDownLabel_3      matlab.ui.control.Label
        REditField_2Label               matlab.ui.control.Label
        REditField_2                    matlab.ui.control.NumericEditField
        GEditField_2Label               matlab.ui.control.Label
        GEditField_2                    matlab.ui.control.NumericEditField
        BEditField_2Label               matlab.ui.control.Label
        BEditField_2                    matlab.ui.control.NumericEditField
        ThemeColor2DropDownLabel_4      matlab.ui.control.Label
        REditField_3Label               matlab.ui.control.Label
        REditField_3                    matlab.ui.control.NumericEditField
        GEditField_3Label               matlab.ui.control.Label
        GEditField_3                    matlab.ui.control.NumericEditField
        BEditField_3Label               matlab.ui.control.Label
        BEditField_3                    matlab.ui.control.NumericEditField
        GIFOptionsTab                   matlab.ui.container.Tab
        PlayersDisplayedEditFieldLabel  matlab.ui.control.Label
        PlayersDisplayedEditField       matlab.ui.control.NumericEditField
        ThemeColor2DropDownLabel_5      matlab.ui.control.Label
        REditField_4Label               matlab.ui.control.Label
        REditField_4                    matlab.ui.control.NumericEditField
        GEditField_4Label               matlab.ui.control.Label
        GEditField_4                    matlab.ui.control.NumericEditField
        BEditField_4Label               matlab.ui.control.Label
        BEditField_4                    matlab.ui.control.NumericEditField
        ThemeColor2DropDownLabel_6      matlab.ui.control.Label
        REditField_5Label               matlab.ui.control.Label
        REditField_5                    matlab.ui.control.NumericEditField
        GEditField_5Label               matlab.ui.control.Label
        GEditField_5                    matlab.ui.control.NumericEditField
        BEditField_5Label               matlab.ui.control.Label
        BEditField_5                    matlab.ui.control.NumericEditField
        ThemeColor2DropDownLabel_7      matlab.ui.control.Label
        REditField_6Label               matlab.ui.control.Label
        REditField_6                    matlab.ui.control.NumericEditField
        GEditField_6Label               matlab.ui.control.Label
        GEditField_6                    matlab.ui.control.NumericEditField
        BEditField_6Label               matlab.ui.control.Label
        BEditField_6                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        backgroundPath = 'background.jpg';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateScoreboardButton
        function GenerateScoreboardButtonPushed(app, event)
            
            app.GenerateScoreboardButton.Enable = false;
            
            app.Label.Text = 'Now Processing...';
            app.Label.FontWeight = 'normal';
            bgColor = app.Label.BackgroundColor;
            app.Label.BackgroundColor = [1 0.5 0.5];
            drawnow
            
            emailDomain = app.EmailDomainEditField.Value;
            group_id = app.ProblemGroupEditField.Value;
            contestStartDate = datetime(app.StartDateDatePicker.Value,'InputFormat',"MM/dd/uuuu");
            contestEndDate = datetime(app.EndDateDatePicker.Value,'InputFormat',"MM/dd/uuuu");
            filename = app.ScoreboardFilenameEditField.Value;
            schoolName = app.SchoolNameEditField.Value;
            pageLimit = 8;
            players = findPlayersByEmail(emailDomain,pageLimit);
            problems = findProblemsByGroup(group_id);
            
            allSolutions = generateSolutionsList(problems,players,contestStartDate,contestEndDate);
            [col,~] = size(allSolutions);
            if col == 0
                app.Label.BackgroundColor = [1 1 0];
                app.Label.Text = {'No Entries were found for';'the info entered'};
                app.Label.FontWeight = 'bold';
                app.GenerateScoreboardButton.Enable = true;
            else                           
                playerScore = findScores(allSolutions);
                writetable(playerScore,strcat(filename,'.xlsx'));
                
                if strcmp(app.PNGSwitch.Value,'On')
                    themeColor1 = [app.REditField.Value/255,app.GEditField.Value/255,app.BEditField.Value/255];
                    themeColor2 = [app.REditField_2.Value/255,app.GEditField_2.Value/255,app.BEditField_2.Value/255];
                    lineColor = [app.REditField_3.Value/255,app.GEditField_3.Value/255,app.BEditField_3.Value/255];

                    pngOptions = struct('textColor',themeColor1,...
                        'lineColor',lineColor,'playerCap',app.MaxPlayersDisplayedEditField.Value,...
                        'background',app.backgroundPath,'themeColor',themeColor2);
                    makeLeaderboard_PNG(filename, allSolutions, problems, schoolName, pngOptions);
                end
                
                if strcmp(app.GIFSwitch.Value,'On')
                    barColor = [app.REditField_4.Value/255,app.GEditField_4.Value/255,app.BEditField_4.Value/255];
                    highlightColor = [app.REditField_5.Value/255,app.GEditField_5.Value/255,app.BEditField_5.Value/255];
                    backgroundColor = [app.REditField_6.Value/255,app.GEditField_6.Value/255,app.BEditField_6.Value/255];

                    gifOptions = struct('barColor',barColor,'playerCap',app.PlayersDisplayedEditField.Value,...
                        'highlightColor',highlightColor,'backgroundColor',backgroundColor);
                    makeLeaderboard_Race(filename,allSolutions,gifOptions)
                end
                
                if strcmp(app.HTMLSwitch.Value,'On')
                    makeLeaderboard_HTML(filename, allSolutions, group_id, problems);
                end
                
                app.Label.BackgroundColor = bgColor;
                app.Label.Text = '';
                app.GenerateScoreboardButton.Enable = true;
            end
        end

        % Button pushed function: ChooseimageButton
        function ChooseimageButtonPushed(app, event)
            [File,Path] = uigetfile({'*.jpg;*.png;*.jfif'});
            drawnow;
            figure(app.UIFigure)
            app.backgroundPath=strcat(Path,File);
            app.BackgroundImageEditField.Value = File;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CodyAmbassadorContestLabel = uilabel(app.UIFigure);
            app.CodyAmbassadorContestLabel.Text = 'Cody Ambassador Contest';
            app.ScoreboardBuilderLabel = uilabel(app.UIFigure);
            app.ScoreboardBuilderLabel.Text = 'Scoreboard Builder';
            app.GenerateScoreboardButton = uibutton(app.UIFigure, 'push');
            app.GenerateScoreboardButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateScoreboardButtonPushed, true);
            app.GenerateScoreboardButton.Text = 'Generate Scoreboard';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OutputfileoptionsPanel = uipanel(app.UIFigure);
            app.OutputfileoptionsPanel.Title = 'Output file options';
            app.PNGSwitchLabel = uilabel(app.OutputfileoptionsPanel);
            app.PNGSwitchLabel.Text = 'PNG';
            app.PNGSwitch = uiswitch(app.OutputfileoptionsPanel, 'slider');
            app.PNGSwitch.Value = 'On';
            app.GIFSwitchLabel = uilabel(app.OutputfileoptionsPanel);
            app.GIFSwitchLabel.Text = 'GIF';
            app.GIFSwitch = uiswitch(app.OutputfileoptionsPanel, 'slider');
            app.GIFSwitch.Value = 'On';
            app.HTMLSwitchLabel = uilabel(app.OutputfileoptionsPanel);
            app.HTMLSwitchLabel.Text = 'HTML';
            app.HTMLSwitch = uiswitch(app.OutputfileoptionsPanel, 'slider');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScoreboardSettingsTab = uitab(app.TabGroup);
            app.ScoreboardSettingsTab.Title = 'Scoreboard Settings';
            app.SchoolNameEditFieldLabel = uilabel(app.ScoreboardSettingsTab);
            app.SchoolNameEditFieldLabel.Text = 'School Name';
            app.SchoolNameEditField = uieditfield(app.ScoreboardSettingsTab, 'text');
            app.SchoolNameEditField.Value = 'University of MathWorks';
            app.EmailDomainEditFieldLabel = uilabel(app.ScoreboardSettingsTab);
            app.EmailDomainEditFieldLabel.Text = 'Email Domain';
            app.EmailDomainEditField = uieditfield(app.ScoreboardSettingsTab, 'text');
            app.EmailDomainEditField.Value = 'mathworks.com';
            app.ProblemGroupEditFieldLabel = uilabel(app.ScoreboardSettingsTab);
            app.ProblemGroupEditFieldLabel.Text = 'Problem Group';
            app.ProblemGroupEditField = uieditfield(app.ScoreboardSettingsTab, 'text');
            app.ProblemGroupEditField.Value = '2808';
            app.StartDateDatePickerLabel = uilabel(app.ScoreboardSettingsTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.ScoreboardSettingsTab);
            app.StartDateDatePicker.Value = datetime([2020 8 10]);
            app.EndDateDatePickerLabel = uilabel(app.ScoreboardSettingsTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.ScoreboardSettingsTab);
            app.EndDateDatePicker.Value = datetime([2020 8 24]);
            app.ScoreboardFilenameEditFieldLabel = uilabel(app.ScoreboardSettingsTab);
            app.ScoreboardFilenameEditFieldLabel.Text = 'Scoreboard Filename';
            app.ScoreboardFilenameEditField = uieditfield(app.ScoreboardSettingsTab, 'text');
            app.ScoreboardFilenameEditField.Value = 'scoreboard';
            app.PNGOptionsTab = uitab(app.TabGroup);
            app.PNGOptionsTab.Title = 'PNG Options';
            app.MaxPlayersDisplayedEditFieldLabel = uilabel(app.PNGOptionsTab);
            app.MaxPlayersDisplayedEditFieldLabel.Text = 'Max Players Displayed';
            app.MaxPlayersDisplayedEditField = uieditfield(app.PNGOptionsTab, 'numeric');
            app.MaxPlayersDisplayedEditField.Limits = [10 30];
            app.MaxPlayersDisplayedEditField.Value = 20;
            app.BackgroundImageEditFieldLabel = uilabel(app.PNGOptionsTab);
            app.BackgroundImageEditFieldLabel.Text = 'Background Image';
            app.BackgroundImageEditField = uieditfield(app.PNGOptionsTab, 'text');
            app.BackgroundImageEditField.Editable = 'off';
            app.BackgroundImageEditField.Value = 'background.jpg';
            app.ChooseimageButton = uibutton(app.PNGOptionsTab, 'push');
            app.ChooseimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseimageButtonPushed, true);
            app.ChooseimageButton.Text = 'Choose image';
            app.ThemeColor2DropDownLabel_2 = uilabel(app.PNGOptionsTab);
            app.ThemeColor2DropDownLabel_2.Text = 'Theme Color 1';
            app.REditFieldLabel = uilabel(app.PNGOptionsTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.PNGOptionsTab, 'numeric');
            app.REditField.Limits = [0 255];
            app.REditField.Value = 99;
            app.GEditFieldLabel = uilabel(app.PNGOptionsTab);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.PNGOptionsTab, 'numeric');
            app.GEditField.Limits = [0 255];
            app.GEditField.Value = 101;
            app.BEditFieldLabel = uilabel(app.PNGOptionsTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.PNGOptionsTab, 'numeric');
            app.BEditField.Limits = [0 255];
            app.BEditField.Value = 105;
            app.ThemeColor2DropDownLabel_3 = uilabel(app.PNGOptionsTab);
            app.ThemeColor2DropDownLabel_3.Text = 'Theme Color 2';
            app.REditField_2Label = uilabel(app.PNGOptionsTab);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.REditField_2.Limits = [0 255];
            app.GEditField_2Label = uilabel(app.PNGOptionsTab);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.GEditField_2.Limits = [0 255];
            app.BEditField_2Label = uilabel(app.PNGOptionsTab);
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.BEditField_2.Limits = [0 255];
            app.ThemeColor2DropDownLabel_4 = uilabel(app.PNGOptionsTab);
            app.ThemeColor2DropDownLabel_4.Text = 'Line Color';
            app.REditField_3Label = uilabel(app.PNGOptionsTab);
            app.REditField_3Label.Text = 'R';
            app.REditField_3 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.REditField_3.Limits = [0 255];
            app.REditField_3.Value = 47;
            app.GEditField_3Label = uilabel(app.PNGOptionsTab);
            app.GEditField_3Label.Text = 'G';
            app.GEditField_3 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.GEditField_3.Limits = [0 255];
            app.GEditField_3.Value = 126;
            app.BEditField_3Label = uilabel(app.PNGOptionsTab);
            app.BEditField_3Label.Text = 'B';
            app.BEditField_3 = uieditfield(app.PNGOptionsTab, 'numeric');
            app.BEditField_3.Limits = [0 255];
            app.BEditField_3.Value = 178;
            app.GIFOptionsTab = uitab(app.TabGroup);
            app.GIFOptionsTab.Title = 'GIF Options';
            app.PlayersDisplayedEditFieldLabel = uilabel(app.GIFOptionsTab);
            app.PlayersDisplayedEditFieldLabel.Text = 'Players Displayed';
            app.PlayersDisplayedEditField = uieditfield(app.GIFOptionsTab, 'numeric');
            app.PlayersDisplayedEditField.Value = 10;
            app.ThemeColor2DropDownLabel_5 = uilabel(app.GIFOptionsTab);
            app.ThemeColor2DropDownLabel_5.Text = 'Bar Color';
            app.REditField_4Label = uilabel(app.GIFOptionsTab);
            app.REditField_4Label.Text = 'R';
            app.REditField_4 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.REditField_4.Limits = [0 255];
            app.GEditField_4Label = uilabel(app.GIFOptionsTab);
            app.GEditField_4Label.Text = 'G';
            app.GEditField_4 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.GEditField_4.Limits = [0 255];
            app.BEditField_4Label = uilabel(app.GIFOptionsTab);
            app.BEditField_4Label.Text = 'B';
            app.BEditField_4 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.BEditField_4.Limits = [0 255];
            app.BEditField_4.Value = 255;
            app.ThemeColor2DropDownLabel_6 = uilabel(app.GIFOptionsTab);
            app.ThemeColor2DropDownLabel_6.Text = 'Text Color';
            app.REditField_5Label = uilabel(app.GIFOptionsTab);
            app.REditField_5Label.Text = 'R';
            app.REditField_5 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.REditField_5.Limits = [0 255];
            app.REditField_5.Value = 99;
            app.GEditField_5Label = uilabel(app.GIFOptionsTab);
            app.GEditField_5Label.Text = 'G';
            app.GEditField_5 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.GEditField_5.Limits = [0 255];
            app.GEditField_5.Value = 101;
            app.BEditField_5Label = uilabel(app.GIFOptionsTab);
            app.BEditField_5Label.Text = 'B';
            app.BEditField_5 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.BEditField_5.Limits = [0 255];
            app.BEditField_5.Value = 105;
            app.ThemeColor2DropDownLabel_7 = uilabel(app.GIFOptionsTab);
            app.ThemeColor2DropDownLabel_7.Text = 'Background Color';
            app.REditField_6Label = uilabel(app.GIFOptionsTab);
            app.REditField_6Label.Text = 'R';
            app.REditField_6 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.REditField_6.Limits = [0 255];
            app.REditField_6.Value = 219;
            app.GEditField_6Label = uilabel(app.GIFOptionsTab);
            app.GEditField_6Label.Text = 'G';
            app.GEditField_6 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.GEditField_6.Limits = [0 255];
            app.GEditField_6.Value = 246;
            app.BEditField_6Label = uilabel(app.GIFOptionsTab);
            app.BEditField_6Label.Text = 'B';
            app.BEditField_6 = uieditfield(app.GIFOptionsTab, 'numeric');
            app.BEditField_6.Limits = [0 255];
            app.BEditField_6.Value = 255;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scoreboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end